classdef appUIMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                            matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ONLINELEARNINGLabel             matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        EvaluationTab                   matlab.ui.container.Tab
        LabelOnlineCountTest            matlab.ui.control.Label
        LabelOnlineResults              matlab.ui.control.Label
        SaveresultsButton               matlab.ui.control.Button
        ButtonTest                      matlab.ui.control.Button
        ButtonOnlineDataAugmentation    matlab.ui.control.Button
        ButtonTrainAndValidate          matlab.ui.control.Button
        UIAxesOnlineEvolutionRecognition  matlab.ui.control.UIAxes
        UIAxesOnlineEvolutionClassification  matlab.ui.control.UIAxes
        ConfigTab                       matlab.ui.container.Tab
        EditFieldNewClass               matlab.ui.control.EditField
        DeleteclassButton               matlab.ui.control.Button
        ExpandclassButton               matlab.ui.control.Button
        UITableOnlineExpansion          matlab.ui.control.Table
        ClassmanagementButtonGroup      matlab.ui.container.ButtonGroup
        ButtonWorkwithnewclasses        matlab.ui.control.RadioButton
        ButtonWorkwithonlyknown         matlab.ui.control.RadioButton
        TextAreaSummary                 matlab.ui.control.TextArea
        LabelConfigSummary              matlab.ui.control.Label
        UserdataPanel                   matlab.ui.container.Panel
        CheckBoxAutoAddPinchOpen        matlab.ui.control.CheckBox
        DropDownUserData                matlab.ui.control.DropDown
        UserDropDownLabel               matlab.ui.control.Label
        SimulatorPanel                  matlab.ui.container.Panel
        OnLabel                         matlab.ui.control.Label
        ButtonSimulatorRelax            matlab.ui.control.Button
        ButtonSimulatorWaveOut          matlab.ui.control.Button
        ButtonSimulatorWaveIn           matlab.ui.control.Button
        ButtonSimulatorPinch            matlab.ui.control.Button
        ButtonSimulatorOpen             matlab.ui.control.Button
        ButtonSimulatorFist             matlab.ui.control.Button
        ImageMyoFake                    matlab.ui.control.Image
        LampIsSimulatorOn               matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        StaticTab                       matlab.ui.container.Tab
        LampReading                     matlab.ui.control.Lamp
        CheckBoxIstraining              matlab.ui.control.CheckBox
        StartautoButton                 matlab.ui.control.Button
        CheckBoxAutoSimulateStatic      matlab.ui.control.CheckBox
        ButtonStaticReadAndPredict      matlab.ui.control.Button
        PredictionsofmodelsPanel        matlab.ui.container.Panel
        ButtonStaticRewardNegative      matlab.ui.control.Button
        ButtonStaticRewardPositive      matlab.ui.control.Button
        LabelStaticModelsExecutionTime  matlab.ui.control.Label
        ModelIncompleteLabel            matlab.ui.control.Label
        ImageStaticPredictionIncomplete  matlab.ui.control.Image
        ModelOnlineLabel                matlab.ui.control.Label
        ImageStaticPredictionOnline     matlab.ui.control.Image
        ModelCompleteLabel              matlab.ui.control.Label
        ImageStaticPredictionComplete   matlab.ui.control.Image
        UIAxesStaticSnapshot            matlab.ui.control.UIAxes
        StreamingTab                    matlab.ui.container.Tab
        DebugButton                     matlab.ui.control.Button
        button_play                     matlab.ui.control.Button
        IsstreamingLamp                 matlab.ui.control.Lamp
        IsstreamingLampLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        is_streaming = false; % Description
        button_simulator_last_selected = [];
        history_experiments; % Description
        seed_rng = 44; % Description
        count_test = 0; % Description
    end
    
    methods (Access = private)
        
        function changeColorSimulatorGestureButton(app, gesture_name)
            color_selected = [0.61,0.93,1.00];
            color_normal = [0.96,0.96,0.96];
            
            if ~isempty(app.button_simulator_last_selected)
                app.button_simulator_last_selected.BackgroundColor = color_normal;
            end
            
            if gesture_name == "fist"
                app.button_simulator_last_selected = app.ButtonSimulatorFist;
            elseif gesture_name == "pinch"
                app.button_simulator_last_selected = app.ButtonSimulatorPinch;
            elseif gesture_name == "open"
                app.button_simulator_last_selected = app.ButtonSimulatorOpen;
            elseif gesture_name == "waveIn"
                app.button_simulator_last_selected = app.ButtonSimulatorWaveIn;
            elseif gesture_name == "waveOut"
                app.button_simulator_last_selected = app.ButtonSimulatorWaveOut;
            elseif gesture_name == "noGesture"
                app.button_simulator_last_selected = app.ButtonSimulatorRelax;
            end
            
            app.button_simulator_last_selected.BackgroundColor = color_selected;
            
            
        end
        
        function setUserData(app, user_id)
            global counter
            global user_set
            global stored_extra_classes
            global stored_qnn
            stored_extra_classes = [];
            stored_qnn =  struct();
            stored_qnn.check = 0;
            
            counter = 0;
            
            generate_rng(app.seed_rng);
            app.count_test = 0;
            
            global path_root user_folder
            global userData orientation user_full_dir
            global myoObject
            
            % pending
            % myoObject.myoData.setUserSource(user_id);
            
            user_folder = "user"+user_id;
            is_legacy = false;
            userData = loadUserByNameAndDir(user_folder, char(user_full_dir), is_legacy);
            rng('default');
            orientation = getOrientation(userData, user_folder);
            
            global params
            
            % models
            global model_complete model_incomplete model_incomplete_copy context_for_model_complete context_for_model_incomplete;
            

            model_complete = load(path_root + "App/matlab/data/models_complete/complete-user" + user_id + ".mat");
            model_incomplete = load(path_root + "App/matlab/data/models_incomplete/incomplete-user" + user_id + ".mat");
            model_incomplete_copy = load(path_root + "App/matlab/data/models_incomplete/incomplete-user" + user_id + ".mat");

            context_for_model_complete = generateContext(params, model_complete.classes_num_to_name);
            context_for_model_incomplete = generateContext(params, model_incomplete.classes_num_to_name);

            h = struct();
            h.classification = [];
            h.recognition = [];
            h.labels = {};
            h.confusion_matrix = {};
            h.add_gesture = {};
            h.add_reward = [];
            
            global extra_dataset index_extra_dataset
            extra_dataset = {};
            index_extra_dataset = 1;
            
            global probability_select
            % priorice new gestures
            
            global base_classes
            base_classes = 4;
            probability_select = ones([1, base_classes])/base_classes;


            global last_prediction_complete last_reward;
            last_prediction_complete = "none";
            last_reward = 0;
            
            app.history_experiments(user_folder) = h;
            
            
            app.selectMode(0);
            
            user_set = true;
           
            
            % if predict at the begining then the CPU can wait correctly
            % app.staticReadAndPredict(null(1));
            
        end
        
        function giveFeedback(app, reward)


            global qnn_online context_online
            global last_sample last_prediction
            global window_size stride
            global myoObject
            global counter_feedback;
            global last_reward;
            
            
            if app.ButtonWorkwithonlyknown.Value
                return;
            end


            counter_feedback = counter_feedback - 1;
            
            last_reward = reward;
            %{
            if last_reward == -1
                last_reward = -2;
            end
            %}
            app.ButtonStaticReadAndPredict.Visible = false;
            app.ButtonStaticRewardPositive.Visible = false;
            app.ButtonStaticRewardNegative.Visible = false;
            % pause(0.1);
                        
            
            features_name = "features_per_windowWin" + window_size + "Stride" + stride;
  
            states = last_sample.(features_name);
            num_windows = size(states, 1);
            
            gt_gestures_pts=zeros([1,num_windows]);
            gt_gestures_pts(1,1)=window_size;

            for k = 1:num_windows-1
                gt_gestures_pts(1,k+1)=gt_gestures_pts(1,k)+stride;
            end
            
            classes_name_to_num = context_online('classes_name_to_num');
            gt_gestures_labels = mapGroundTruthToLabelsWithPts(gt_gestures_pts, last_sample.groundTruthIndex, last_prediction, 0.2);
            gt_gestures_labels_num = mapGestureLabelsToNumbers(num_windows, gt_gestures_labels, classes_name_to_num);

            % only is labeled the ground truth part
            
            action_no_gesture = classes_name_to_num("noGesture");
            
            for index_state=1:num_windows
                action = gt_gestures_labels_num(index_state);
                %{
                if action == action_no_gesture
                    % we dont know if was correct or incorrect
                    continue;
                end
                %}
                state = states(index_state, :);
                new_state = state;
                is_terminal = index_state == num_windows;
                qnn_online.saveExperienceReplay(state', action, last_reward, new_state', is_terminal)
                
            end
            
            global extra_dataset index_extra_dataset class_added
            global last_gesture stored_extra_classes
            
            % classes_name_to_num = context_online('classes_name_to_num');
            
            
            classes = ["waveOut", "waveIn", "fist", "noGesture"];
                
            classes_merged = [classes, stored_extra_classes];
            
            add_to_dataset = true;
            global real_gestureName
            for i=1:length(classes)
                if classes(i) == real_gestureName % string(last_gesture.gestureName)
                    add_to_dataset = false;
                    break;
                end
            end
            
            train_from_dataset = false;
            
            global probability_select
            
            
            if add_to_dataset
                limit = 50;
                last_sample.gestureName = real_gestureName; % string(last_gesture.gestureName);


                if ~class_added(last_sample.gestureName)
                    
                    
                    data = app.generateDataAugmentation(last_sample);
               
                    for i=1:length(data)
                        extra_dataset{index_extra_dataset} = data{i};
                        index_extra_dataset = mod(index_extra_dataset + 1, limit);
                        if index_extra_dataset == 0
                            index_extra_dataset = limit;
                        end
                    end
                    
                    
                    class_added(last_sample.gestureName) = true;
                    train_from_dataset = true;


                    ks = keys(class_added);
                    
                    all_sampled = true;
                    for i=1:length(ks)
                        if ~class_added(ks{i})
                            all_sampled = false;
                            break;
                        end
                        
                    end
                    
                    if all_sampled
                        probability_select = ones([1, length(ks)])/length(ks);
                    end
                    
                else
                    extra_dataset{index_extra_dataset} = last_sample;
                    index_extra_dataset = mod(index_extra_dataset + 1, limit);
                    if index_extra_dataset == 0
                        index_extra_dataset = limit;
                    end
                end


            end
            global counter
            counter = counter + 1;
            if mod(counter, 2) == 0
                % train full each intervals
                train_from_dataset = true;
            end
                
            
            app.learn(train_from_dataset);
            
            app.onlineQNNTest();
            global evaluation_mode;
            if evaluation_mode == "training"
                if app.CheckBoxAutoSimulateStatic.Value
                    % Pending
                    % myoObject.myoData.setSimulationRandom();
                end
                
            else
                
                % pending
                %{
                selected_class = randsample(classes_merged, 1, true, probability_select);
                try
                    myoObject.myoData.setSimulationGestures(selected_class);
                catch DUMMY
                    myoObject.myoData.setSimulationGestures("waveIn");
                end
                %}
            end

            if app.CheckBoxAutoSimulateStatic.Value
                app.staticReadAndPredict(null(1));
            end
            
            app.ButtonStaticReadAndPredict.Visible = true;
            app.ButtonStaticRewardPositive.Visible = true;
            app.ButtonStaticRewardNegative.Visible = true;
            
        end
        
        function transferNetwork(app, model_reference, classes)
            global qnn_online context_online 
            global userData params
            global known_dataset_training known_dataset_test
            
            global stored_qnn stored_extra_classes
            
            classes_num_to_name = deepCopyMap(model_reference.classes_num_to_name, 'double', 'char');


            classes_to_expand = length(classes);
            classes_actual = prod(model_reference.model.sequential_network.shape_output);

            
            for i=1:classes_to_expand
                classes_num_to_name(classes_actual + i) = char(classes(i));
            end
            
            keys_classes = keys(classes_num_to_name);
            output = "";
            for i=1:length(keys_classes)
                key = keys_classes{i};
                output = output +  sprintf("%s->%d\n", classes_num_to_name(key), key);
            end
            app.TextAreaSummary.Value = output;


            if stored_qnn.check > 0
                m_len =  min(length(stored_extra_classes),length(classes));
                exist = true;
                for i=1:m_len
                    if classes(i) ~= stored_extra_classes(i)
                        exist = false;
                        break;
                    end
                end
                if exist
                    qnn_online = stored_qnn.qnn_online;
                    context_online = stored_qnn.context_online;
                    known_dataset_training = stored_qnn.known_dataset_training;
                    known_dataset_test = stored_qnn.known_dataset_test;


                    return;
                end
            end


            output_neurons_new = classes_actual + classes_to_expand;

            sequential_network_length = length(model_reference.model.sequential_network.network);
            network_new = cell([1, sequential_network_length]);
            
            temp = model_reference.model.sequential_network.network(:);
            for i=1:sequential_network_length-1
                network_new{1, i} = temp{i};
                
                if mod(i,2) ~= 0
                    
                    network_new{1, i}.vdw = 0;
                    network_new{1, i}.vdb = 0;
                    network_new{1, i}.sdw = 0;
                    network_new{1, i}.sdb = 0;
                end
            end
            clear temp;
            
            % last layer should be new due to different neurons
            last_original = model_reference.model.sequential_network.network{sequential_network_length};
            last_layer = Dense(output_neurons_new, "xavier", prod(network_new{sequential_network_length-1}.shape_output));
            
            
            %{
            empty_part_weight = zeros([classes_to_expand, size(last_original.vdw, 2)]);
            empty_part_bias = zeros([classes_to_expand, size(last_original.vdb, 2)]);
            
            last_layer.vdw = [last_original.vdw; empty_part_weight];
            last_layer.vdb = [last_original.vdb; empty_part_bias];
            last_layer.sdw = [last_original.sdw; empty_part_weight];
            last_layer.sdb = [last_original.sdb; empty_part_bias];

            last_layer.vdw = 0;
            last_layer.vdb = 0;
            last_layer.sdw = 0;
            last_layer.sdb = 0;


            last_layer.weights(1:classes_actual, :) = last_original.weights(1:classes_actual, :);
            last_layer.bias(1:classes_actual, :) = last_original.bias(1:classes_actual, :);
            
            %}
            
            last_layer.vdw = 0;
            last_layer.vdb = 0;
            last_layer.sdw = 0;
            last_layer.sdb = 0;
        
            network_new(sequential_network_length) = {last_layer};
            
            sequential_new = Sequential({});
            sequential_new.network = network_new;
            sequential_new.shape_input = sequential_new.network{1}.shape_input;
            sequential_new.shape_output = sequential_new.network{sequential_network_length}.shape_output;


            % Sequential(network_new)
            qnn_online = QNeuralNetwork(Sequential({}), sequential_new, ...
                model_reference.model.nnConfig, ...
                model_reference.model.qLearningConfig, ...
                model_reference.model.functionExecuteEpisode);
            
            qnn_online.transferGameReplay(model_reference.model.gameReplay);
            qnn_online.setCustomRunEpisodes(@customRunEpisodesEMG);            
            
            
            global labels;


            labels = cell([1, length(classes_num_to_name)]);
            for i=1:length(classes_num_to_name)
                labels{i} = classes_num_to_name(i);
            end
            
            context_online = generateContext(params, classes_num_to_name);
            
            dataset_complete = userData.training;
            dataset_complete_test = userData.testing;
            known_dataset_training = {};
            known_dataset_test = {};
            known_classes = string(values(model_reference.classes_num_to_name));
            known_classes_test = string(values(classes_num_to_name));
            
            for index_dataset_complete=1:length(dataset_complete)
                gesture = dataset_complete{index_dataset_complete}.gestureName;
                gesture_test = dataset_complete_test{index_dataset_complete}.gestureName;
                if searchStringInArray(known_classes, gesture) ~= -1
                    known_dataset_training = [known_dataset_training, dataset_complete(index_dataset_complete)];
                end
                if searchStringInArray(known_classes_test, gesture_test) ~= -1
                    known_dataset_test = [known_dataset_test, dataset_complete_test(index_dataset_complete)];
                end
            end


            stored_qnn.qnn_online = qnn_online;
            stored_qnn.context_online = context_online;
            stored_qnn.known_dataset_training = known_dataset_training;
            stored_qnn.known_dataset_test = known_dataset_test;
            stored_qnn.check = 1;


        end
        
        function data = generateDataAugmentation(app, sample)
            global path_root orientation window_size stride
            
            features_name = sample.features_name;
            
            data_augmentation_moving_avg_box = {};
            for i=2:15
                emg_new = movmean(sample.emg, i);
                features_per_window = extractFeaturesByWindowStride(path_root, orientation, window_size, stride, emg_new);
                
                data_augmentation_moving_avg_box = [data_augmentation_moving_avg_box, ...
                    {struct("emg", emg_new, ...
                        features_name, features_per_window, ...
                        "groundTruthIndex", sample.groundTruthIndex, ...
                        "gestureName", sample.gestureName)}]; %#ok<*AGROW> 
                
            end
            
            data_augmentation_shift = {};
            for i=1:10
                shift = i*5;
                emg_length = size(sample.emg, 1);
                emg_new_left = zeros(size(sample.emg));
                emg_new_right = zeros(size(sample.emg));
                emg_new_left(shift:emg_length, :) = sample.emg(1:(emg_length-shift+1), :);
                groundTruthLeft = [0, 0];
                groundTruthLeft(1) = min(sample.groundTruthIndex(1) + shift, emg_length);
                groundTruthLeft(2) = min(sample.groundTruthIndex(2) + shift, emg_length);
                
                emg_new_right(1:(emg_length-shift+1), :) = sample.emg(shift:emg_length, :);
                groundTruthRight = [0, 0];
                groundTruthRight(1) = max(sample.groundTruthIndex(1) - shift, 1);
                groundTruthRight(2) = max(sample.groundTruthIndex(2) - shift, 1);
                
                
                features_per_window_left = extractFeaturesByWindowStride(path_root, orientation, window_size, stride, emg_new_left);
                
                data_augmentation_shift = [data_augmentation_shift, ...
                    {struct("emg", emg_new_left, ...
                    features_name, features_per_window_left, ...
                    "groundTruthIndex", groundTruthLeft, ...
                    "gestureName", sample.gestureName)}];
                
                features_per_window_right = extractFeaturesByWindowStride(path_root, orientation, window_size, stride, emg_new_right);
                
                data_augmentation_shift = [data_augmentation_shift, ...
                    {struct("emg", emg_new_right, ...
                    features_name, features_per_window_right, ...
                    "groundTruthIndex", groundTruthRight, ...
                    "gestureName", sample.gestureName)}];
                
            end
            
            data = [{sample}, ...
                data_augmentation_moving_avg_box, ...
                data_augmentation_shift];
        end
        
        function can = nextUser(app)
            global counter_feedback;
            global users_selected users_selected_index
            global evaluation_mode
            counter_feedback = 50;
            
            users_selected_index = users_selected_index + 1;
            
            if users_selected_index > length(users_selected)
                can = false;
            else
                
                app.DropDownUserData.Value = users_selected(users_selected_index);
    
                app.setUserData(users_selected(users_selected_index));
                can = true;
                
                if evaluation_mode == "training" || evaluation_mode == "centered"
                    app.onlineAddRepresentative(null(1));
                    app.onlineQNNTrain(null(1));
                    
                elseif evaluation_mode == "training_known"
                    
                    app.onlineQNNTrain(null(1));
                    
                end
                app.staticReadAndPredict(null(1));
            end
            
            
        end
        
        function showConfusionMatrix(app, confmat, labels)
            confmat(isnan(confmat))=0; % in case there are NaN elements
            numlabels = size(confmat, 1); % number of labels
            
            % calculate the percentage accuracies
            confpercent = 100*confmat./repmat(sum(confmat, 1),numlabels,1);
            
            % plotting the colors
            imagesc(confpercent);
            title(sprintf('Accuracy: %.2f%%', 100*trace(confmat)/sum(confmat(:))));
            ylabel('Output Class'); xlabel('Target Class');
            
            % set the colormap
            colormap(flipud(gray));
            
            % Create strings from the matrix values and remove spaces
            textStrings = num2str([confpercent(:), confmat(:)], '%.1f%%\n%d\n');
            textStrings = strtrim(cellstr(textStrings));
            
            % Create x and y coordinates for the strings and plot them
            [x,y] = meshgrid(1:numlabels);
            hStrings = text(x(:),y(:),textStrings(:), ...
                'HorizontalAlignment','center');
            
            % Get the middle value of the color range
            midValue = mean(get(gca,'CLim'));
            
            % Choose white or black for the text color of the strings so
            % they can be easily seen over the background color
            textColors = repmat(confpercent(:) > midValue,1,3);
            set(hStrings,{'Color'},num2cell(textColors,2));
            
            % Setting the axis labels
            set(gca,'XTick',1:numlabels,...
                'XTickLabel',labels,...
                'YTick',1:numlabels,...
                'YTickLabel',labels,...
                'TickLength',[0 0]);

            
        end
        
        function learn(app, use_dataset)
            global qnn_online context_online
            global known_dataset_training
            global path_to_framework path_root
            global extra_dataset
            if use_dataset
                
                dataset = [extra_dataset, known_dataset_training];
            
                dataset = dataset(randperm(numel(dataset)));
                            
                do_validation = false;
                context_online('user_gestures_training') = dataset;
                
                general_epochs = 3;
                trainAndValidate(path_to_framework, path_root, qnn_online, ...
                                                    general_epochs, do_validation, context_online, 1);
            else
                verbose_level = 0;
                t = 4;
                % qnn_online.alpha = qnn_online.nnConfig.learning_rate/(1 + qnn_online.nnConfig.decay_rate_alpha * t);
                qnn_online.alpha = qnn_online.nnConfig.learning_rate;
                qnn_online.updateQNeuralNetworkTarget();
                
                for i=1:4
                    qnn_online.learnFromExperienceReplay(t, verbose_level-1);
                end
            end
        end
        
        function selectMode(app, mode)
            
            global class_added base_classes
            global new_classes model_incomplete_copy myoObject
            global qnn_online context_online 
            global userData params
            global known_dataset_training known_dataset_test
            global labels path_root;
            global stored_extra_classes;
            
            user_id = app.DropDownUserData.Value;
            model_reference = load(path_root + "App/matlab/data/models_incomplete/incomplete-user" + user_id + ".mat");


            if mode == 1
                class_added = containers.Map();
                
                exist_classes = ~isempty(stored_extra_classes);
                
                if exist_classes
                    for i=1:length(stored_extra_classes)
                        class_added(stored_extra_classes(i)) = false;
                    end
                    new_classes = stored_extra_classes;
                else
                    new_classes = [];
                    if app.CheckBoxAutoAddPinchOpen.Value
                        class_added("pinch") = false;
                        class_added("open") = false;
                        new_classes = ["pinch", "open"];
                    end
                end
                
                global probability_select
                global base_classes
                num_c = base_classes + length(new_classes);
                probability_select = ones([1, num_c])/num_c;


                app.transferNetwork(model_reference, new_classes);
                
            elseif mode == 0


                qnn_online = model_reference.model;
                
                classes_num_to_name = deepCopyMap(model_reference.classes_num_to_name, 'double', 'char');


                labels = cell([1, length(classes_num_to_name)]);
                for i=1:length(classes_num_to_name)
                    labels{i} = classes_num_to_name(i);
                end
                
                context_online = generateContext(params, classes_num_to_name);
                
                dataset_complete = userData.training;
                dataset_complete_test = userData.testing;
                known_dataset_training = {};
                known_dataset_test = {};
                known_classes = string(values(model_reference.classes_num_to_name));
                known_classes_test = string(values(classes_num_to_name));
                
                for index_dataset_complete=1:length(dataset_complete)
                    gesture = dataset_complete{index_dataset_complete}.gestureName;
                    gesture_test = dataset_complete_test{index_dataset_complete}.gestureName;
                    if searchStringInArray(known_classes, gesture) ~= -1
                        known_dataset_training = [known_dataset_training, dataset_complete(index_dataset_complete)];
                    end
                    if searchStringInArray(known_classes_test, gesture_test) ~= -1
                        known_dataset_test = [known_dataset_test, dataset_complete_test(index_dataset_complete)];
                    end
                end
                
                keys_classes = keys(classes_num_to_name);
                output = "";
                for i=1:length(keys_classes)
                    key = keys_classes{i};
                    output = output +  sprintf("%s->%d\n", classes_num_to_name(key), key);
                end
                app.TextAreaSummary.Value = output;
                
                
                probability_select = ones([1, base_classes])/base_classes;
                
                
            end
            app.onlineQNNTest();
            % Pending
            % myoObject.myoData.setSimulationRandom();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global deviceType myoObject
            global use_online
            global users_selected users_selected_index
            global evaluation_mode
            global user_set
            global indices
            indices = [];
            
                
            user_set = false;
            
            global window_size stride params


            window_size = 300;
            stride = 30;
            params.window_size = window_size;
            params.stride = stride;

            myoSimulation = "fake";  % real fake


            global model_auto
            
            model_auto = "training_known-nopinchnoopen";
            
            evaluation_mode = "training_known";
            
            users_selected = [103 14 1 13 15    19    25    27    29    30    32    34    37    39    45    51    52    54    55    59 63    67    70    72    75    76    78    82    84    88    90    92    93    94    96   102   104   112 113   117   118   119   120   129   131   134   135   136   139   142   146   151   154   155   158   159   161 163   165   167   168   169   179   181   182   188   193   198   199   200   211   212   213   214   223   224 226   235   236   238   239   241   244   250   251   253   254   264   267   272   278   279   284   286   287 288   291   293   294   303];
            % users_selected = [34    37    39    45    51    52    54    55    59 63    67    70    72    75    76    78    82    84    88    90    92    93    94    96   102   103   104   112 113   117   118   119   120   129   131   134   135   136   139   142   146   151   154   155   158   159   161 163   165   167   168   169   179   181   182   188   193   198   199   200   211   212   213   214   223   224 226   235   236   238   239   241   244   250   251   253   254   264   267   272   278   279   284   286   287 288   291   293   294   303];
            % users_selected = [1];
            users_selected_index = 0;
            
            use_online = true;
            
            deviceType = DeviceName.myo;
            disconnectMyo(myoObject);
            cleanAllTimers(); % sanity stop
            [myoObject, ~] = connectMyo(myoSimulation);
            myoObject.myoData.stopStreaming();

            app.LampIsSimulatorOn.Enable = true;
            
            app.history_experiments = containers.Map();
            
            global path_to_framework path_root
            path_root = "C:/Users/danny/Documents/Programming/MATLAB/TesisEMG/";
            path_to_framework = "C:/Users/danny/Documents/Programming/MATLAB/MATLABMagodyFramework";% "C:\Users\Magody\Documents\GitHub\MATLABMagodyFramework\magody_framework"; "/home/magody/programming/MATLAB/deep_learning_from_scratch/magody_framework";


            global user_full_dir sample_time_ms
            
            user_full_dir = path_root +"Data/preprocessing/";


            sample_time_ms = 990;
            addpath(genpath(path_root + "ModelingAndExperiments/RLSetup"));
            addpath(genpath(path_root + "GeneralLib"));
            addpath(genpath(path_root + "App/matlab/lib/Myo"));
            addpath(path_root + "App/matlab/lib/TimersHandle");
            addpath(genpath(path_root + "App/matlab/utils/timers"));
            addpath(path_root + "App/matlab/models/device");
            addpath(genpath(path_root + "App/matlab/data"));
            addpath(genpath(path_root + "App/matlab/media"));
            addpath(genpath(path_to_framework));
            
            num_users = 306;
            items_user = cell([1, num_users]);
            items_data_user = zeros([1, num_users]);
            for user_id=1:num_users
                items_user{user_id} =  ['user' num2str(user_id)];
                items_data_user(user_id) = user_id;
            end
            app.DropDownUserData.Items = items_user;
            app.DropDownUserData.ItemsData = items_data_user;
            
            
            app.UITableOnlineExpansion.ColumnFormat = {'char'};
            app.UITableOnlineExpansion.ColumnEditable = [true];
        end

        % Button pushed function: button_play
        function togglePlay(app, event)
            global myoObject h plot_call_counter;
            if app.is_streaming
                myoObject.myoData.stopStreaming();
                stop(timerfindall);
                app.is_streaming = false;
                app.button_play.Icon = 'button_play.png';
            else
                myoObject.myoData.startStreaming();
                app.is_streaming = true;
                app.button_play.Icon = 'button_pause.png';

                clf();
                h = animatedline;


                plot_call_counter = 0;
                timer_plotAnimatedEMG = timer('Name', 'timer_plotAnimatedEMG', 'TimerFcn', @timerPlotAnimatedEMG, ...
                    'ExecutionMode', 'fixedRate', 'Period', 0.2);
                start(timer_plotAnimatedEMG);

                timer_snapshotEMG = timer('Name', 'timer_snapshotEMG', 'TimerFcn', @timerSnapshotEMG, ...
                    'ExecutionMode', 'fixedRate', 'Period', 1);
                start(timer_snapshotEMG);
            end


            app.IsstreamingLamp.Enable = app.is_streaming;
        end

        % Button pushed function: DebugButton
        function debug(app, event)


        end

        % Button pushed function: ButtonSimulatorFist
        function simulateFist(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("fist");
            
            app.changeColorSimulatorGestureButton("fist");
        end

        % Button pushed function: ButtonSimulatorPinch
        function simulatePinch(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("pinch");
            app.changeColorSimulatorGestureButton("pinch");
        end

        % Button pushed function: ButtonSimulatorOpen
        function simulateOpen(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("open");
            app.changeColorSimulatorGestureButton("open");
        end

        % Button pushed function: ButtonSimulatorRelax
        function simulateRelax(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("noGesture");
            app.changeColorSimulatorGestureButton("noGesture");
        end

        % Button pushed function: ButtonSimulatorWaveIn
        function simulateWaveIn(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("waveIn");
            app.changeColorSimulatorGestureButton("waveIn");
        end

        % Button pushed function: ButtonSimulatorWaveOut
        function simulateWaveOut(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("waveOut");
            app.changeColorSimulatorGestureButton("waveOut");
        end

        % Button pushed function: ButtonStaticReadAndPredict
        function staticReadAndPredict(app, event)
            global deviceType myoObject sample_time_ms path_root
            global window_size stride orientation
            global model_complete model_incomplete
            global context_for_model_complete context_for_model_incomplete
            global last_sample last_prediction
            global qnn_online use_online context_online
            
            correct = false;
            
            while ~correct
                
                number_gestures_to_simulate = 1;
                number_integer = floor(number_gestures_to_simulate);
                excedent = number_gestures_to_simulate - number_integer;
                if number_integer >= 1
                    if number_integer == 1
                        wait_simulation = 0.1;
                    else
                        wait_simulation = 0.1 + ((0.1 + 0.9) * number_integer - 1);
                    end
                else
                    wait_simulation = -0.2* excedent;
                end
                wait_simulation = wait_simulation +  (0.1 + 0.9) * excedent;
    
                if deviceType == DeviceName.myo
                    myoObject.myoData.stopStreaming();
                    myoObject.myoData.clearLogs();
                    myoObject.myoData.startStreaming();
                    % start executes inmediately
                end


                activate = true;
                emg_stored_length = 0;
                last_norm = -1;
                while emg_stored_length < 1100
                    norm = floor(emg_stored_length/10) * 10;
                    if mod(norm, 100) == 0
                        if norm ~= last_norm
                            last_norm = norm;
                            disp(emg_stored_length);
                        end
                    end
                    if activate && emg_stored_length > 100 && emg_stored_length < 200
                        disp("BEGIN!!!");
                        activate = false;
                        app.LampReading.Enable = true;
                        pause(0.2);
                    end
                    emg_stored_length = length(myoObject.myoData.emg_log);
                end
                disp("END!!");
                myoObject.myoData.stopStreaming();
                % cleanAllTimers();
                app.LampReading.Enable = false;
                pause(0.2);


                emg_stored_length = size(myoObject.myoData.emg_log, 1);


                sample_begin = max(1, emg_stored_length-sample_time_ms+1);
    
                emg = myoObject.myoData.emg_log(sample_begin:emg_stored_length, :);
                % emg = myoObject.myoData.last_gesture.emg;
    
    
                tic_toc_feature_extraction = tic;
                disp("DEBUG:")
                disp(path_root);
                disp(orientation);
                disp(window_size);
                disp(stride);
                disp(emg);

                features_per_window = extractFeaturesByWindowStride(path_root, orientation, window_size, stride, emg);
                time_used_for_feature_extraction_ms = toc(tic_toc_feature_extraction) * 1000;
    
    
                features_name = "features_per_windowWin" + window_size + "Stride" + stride;

                groundTruthIndex = [0, 0];
                
                try
                   groundTruthIndex = getMuscularActivityGroundTruth(emg);
                
                    % if myoObject.myoData.last_gesture.gestureName ~= "noGesture"
                        % groundTruthIndex = myoObject.myoData.last_gesture.groundTruthIndex;
                    
                    % end
                    last_sample = struct("emg", emg, features_name, features_per_window, "groundTruthIndex", groundTruthIndex, "features_name", features_name);
                    
                catch ME
                    disp(ME);
                    % last_samplep = struct("emg", emg_supp, features_name, features_per_window, "groundTruthIndex", groundTruthIndexSupp, "features_name", features_name);
                    % last_sample = last_sample_supp;
                end
                                
                context_for_model_complete('user_gestures_testing') = {last_sample};
                context_for_model_incomplete('user_gestures_testing') = {last_sample};
    
                tic_toc_qnn_prediction = tic;
                try
                    % history_test_complete = model_complete.model.runEpisodes(@getRewardEMG, 3, context_for_model_complete, 0);
                    correct = true;
                catch
                    % pause(0.2);
                end
            end
            % global last_gesture
            % last_gesture = myoObject.myoData.last_gesture;history_test_incomplete = model_incomplete.model.runEpisodes(@getRewardEMG, 3, context_for_model_incomplete, 0);
             history_test_complete = model_complete.model.runEpisodes(@getRewardEMG, 3, context_for_model_complete, 0);
            
            history_test_incomplete = model_incomplete.model.runEpisodes(@getRewardEMG, 3, context_for_model_incomplete, 0);
            
            model_class_prediction_complete = string(history_test_complete.history_responses{1}.class); % string(myoObject.myoData.last_gesture.gestureName);% string(history_test_complete.history_responses{1}.class);
            model_class_prediction_incomplete = string(history_test_incomplete.history_responses{1}.class);

            global real_gestureName
            real_gestureName = model_class_prediction_complete;
            mapGestureLabelsToImages = containers.Map(...
                ["noGesture", "fist", "open", "pinch", "waveOut", "waveIn", "unknown"],...
                ["relax.png", "fist.png", "open.png", "pinch.png", "waveOut.png", "waveIn.png", "unknown.png"]);

            app.ImageStaticPredictionComplete.ImageSource = mapGestureLabelsToImages(model_class_prediction_complete);
            app.ImageStaticPredictionIncomplete.ImageSource = mapGestureLabelsToImages(model_class_prediction_incomplete);

            
            if use_online
                context_online('user_gestures_testing') = {last_sample};
                history_test_online = qnn_online.runEpisodes(@getRewardEMG, 3, context_online, 0);  
                model_class_prediction_online = string(history_test_online.history_responses{1}.class);
                
                    
                if app.CheckBoxIstraining.Value
                    class_num_to_name = context_online("classes_num_to_name");
                    class_name_to_num = context_online("classes_name_to_num");
                    
                    Qval = zeros([1, class_num_to_name.Count]);
                    Qval(class_name_to_num(model_class_prediction_online)) = 1;
                    epsilon = 1;
                    all_added = true;
                    global class_added;

                    if ~isempty(class_added)
                    
                        ks = keys(class_added);
                        for i=1:length(ks)
                            if ~class_added(ks{i})
                                all_added = false;
                            end
                        end
                    end
                    
                    if all_added
                        epsilon = 0.2;
                    end
                    
                    [~, action_index] = QLearning.selectActionQEpsilonGreedy(Qval, epsilon, length(Qval), false);
                    
                    model_class_prediction_online = string(class_num_to_name(action_index));
                    
                    
                end
                
                last_prediction = model_class_prediction_online;
                
                if isKey(mapGestureLabelsToImages, model_class_prediction_online)
                    app.ImageStaticPredictionOnline.ImageSource = mapGestureLabelsToImages(model_class_prediction_online);
                else
                    app.ImageStaticPredictionOnline.ImageSource = mapGestureLabelsToImages("unknown");
                end


            end
            time_used_for_qnn_prediction_ms = toc(tic_toc_qnn_prediction) * 1000 ;

            time_used_total_ms = time_used_for_feature_extraction_ms + time_used_for_qnn_prediction_ms;

            text_time = sprintf("Feature Extraction: %.2f[ms] (%.1f %%)\nPrediction: %.2f[ms] (%.1f %%)", ...
                time_used_for_feature_extraction_ms, ...
                100*time_used_for_feature_extraction_ms/time_used_total_ms, ...
                time_used_for_qnn_prediction_ms, ...
                100*time_used_for_qnn_prediction_ms/time_used_total_ms);


            app.LabelStaticModelsExecutionTime.Text = text_time;

            
            global last_prediction_complete
            last_prediction_complete = model_class_prediction_complete;
            
            
            plot(app.UIAxesStaticSnapshot, emg);
            rectangle(app.UIAxesStaticSnapshot, 'Position',[groundTruthIndex(1) -1  groundTruthIndex(2)-groundTruthIndex(1) 2],'EdgeColor','g')

            global counter_feedback auto;
            
            if app.CheckBoxAutoSimulateStatic.Value
                if counter_feedback > 1
                    
                    if model_class_prediction_complete == model_class_prediction_online
                        app.giveFeedback(1);
                    else
                        app.giveFeedback(-1);
                    end
                else
                    app.saveOnlineResults(null(1));
                    app.nextUser();
                end
            end


        end

        % Callback function
        function onlineAddClassAction(app, event)
            [m, n] = size(app.UITableOnlineExpansion.Data);
            data_copy = app.UITableOnlineExpansion.Data;
            data_new = cell([m+1, n]);
            data_new(1:m, 1:n) = data_copy;
            app.UITableOnlineExpansion.Data = data_new;
            
        end

        % Button pushed function: ExpandclassButton
        function addNewClassToOnline(app, event)
            
            global model_incomplete_copy
            global stored_extra_classes
            global stored_qnn
            global user_set
            
            text = app.EditFieldNewClass.Value;
            if ~user_set
                app.setUserData(app.DropDownUserData.Value);
            end
            if text == ""
                return;
            end
            
            app.ButtonWorkwithnewclasses.Value = true;
            

            data_table_raw = app.UITableOnlineExpansion.Data;
            
            
            data_table = {};
            j = 1;
            for i=1:length(data_table_raw)
                if isempty(data_table_raw{i, 1})
                    continue;
                end
                data_table{j, 1} = char(data_table_raw{i});
                j = j + 1;
            end
            data_table{j, 1} = text;
            
            app.UITableOnlineExpansion.Data = data_table;
            
            
            stored_extra_classes = [];
            
            table_len = length(data_table);
            
            for i=1:table_len
                if isempty(data_table{i})
                    continue;
                end
                stored_extra_classes = [stored_extra_classes, string(data_table{i})];
            end
            
            %{
            app.UITableOnlineExpansion.Data = {'custom'};
            
            [m, n] = size(app.UITableOnlineExpansion.Data);
            data_copy = app.UITableOnlineExpansion.Data;
            data_new = cell([m+1, n]);
            data_new(1:m, 1:n) = data_copy;
            app.UITableOnlineExpansion.Data = data_new;
            %}
            
            global probability_select base_classes
            total_classes = length(stored_extra_classes) + base_classes;
            probability_select = ones([1, total_classes])/total_classes;
            
            
            app.selectMode(1);
            stored_qnn.check = 0;
            app.transferNetwork(model_incomplete_copy, stored_extra_classes) %#ok<*NBRAK> 
            app.onlineQNNTest();

        end

        % Button pushed function: ButtonTest
        function onlineQNNTest(app, event)
            global qnn_online context_online
            global known_dataset_test
            global user_folder
            
            context_online('user_gestures_validation') = [known_dataset_test];
            
            [summary, ~, history_test] = ExperimentHelper.testModelIndividual(qnn_online, true, context_online, 0);
        
            global labels;
            % app.showConfusionMatrix(history_test.confusion_matrix, labels);
            classification = summary.classification_test*100;
            recognition = summary.recognition_test*100;
        
        
            app.LabelOnlineResults.Text = sprintf("TEST (%d samples):\nClassification: %.2f %%\nRecognition: %.2f %%", ...
                length(known_dataset_test), ...
                classification, ...
                recognition);
            
            global last_prediction_complete last_reward;
            history_user = app.history_experiments(user_folder);
            history_user.classification = [history_user.classification, classification];
            history_user.recognition = [history_user.recognition, recognition];
            history_user.labels = labels;
            history_user.add_gesture = [history_user.add_gesture, last_prediction_complete];
            history_user.add_reward = [history_user.add_reward, last_reward];
            history_user.confusion_matrix = [history_user.confusion_matrix, history_test.confusion_matrix];
            
            app.history_experiments(user_folder) = history_user;
            plot(app.UIAxesOnlineEvolutionClassification, history_user.classification);
            plot(app.UIAxesOnlineEvolutionRecognition, history_user.recognition);
            
            
            app.count_test = app.count_test + 1;
            app.LabelOnlineCountTest.Text = sprintf("Count: %d", app.count_test);
            
        end

        % Button pushed function: ButtonTrainAndValidate
        function onlineQNNTrain(app, event)
            global qnn_online context_online
            global known_dataset_training
            global path_to_framework path_root
            
            app.ButtonTrainAndValidate.Visible = false;
            pause(0.1);
            
            known_dataset_training_rand = known_dataset_training(randperm(numel(known_dataset_training)));

            known_dataset_training_length = length(known_dataset_training_rand);
            
            index_end_training = known_dataset_training_length - 10;
            
            do_validation = true;
            context_online('user_gestures_training') = known_dataset_training_rand(1:index_end_training);
            if do_validation
                context_online('user_gestures_validation') = known_dataset_training_rand((index_end_training+1):known_dataset_training_length);
            end
            
    
            general_epochs = 5;
            [~, summary, ~] = trainAndValidate(path_to_framework, path_root, qnn_online, ...
                                                general_epochs, do_validation, context_online, 1);

            global last_prediction_complete last_reward;
            last_prediction_complete = "dataset";
            last_reward = 0;
            
            app.onlineQNNTest();

            app.ButtonTrainAndValidate.Visible = true;
            
            %uiwait(msgbox(sprintf("Finish train, %.2f, %.2f", ...
            %    summary{general_epochs, 2}.classification_validation.accuracy, ...
            %    summary{general_epochs, 2}.recognition_validation.accuracy),'Success','modal'));
            
            
        end

        % Button pushed function: ButtonStaticRewardPositive
        function feedbackPositive(app, event)
            app.giveFeedback(1);
        end

        % Button pushed function: ButtonStaticRewardNegative
        function feedbackNegative(app, event)
            app.giveFeedback(-1);
        end

        % Button pushed function: ButtonOnlineDataAugmentation
        function onlineAddRepresentative(app, event)
            global myoObject
            global known_dataset_training
            global window_size stride
            global path_root orientation
            global new_classes
            
            
            app.ButtonOnlineDataAugmentation.Visible = false;
            
            data = [];
            pause(0.1);
            for i=1:length(new_classes)
                c = new_classes(i);
                % pending
                emg1 = myoObject.myoData.getSample(c);
                features_per_window1 = extractFeaturesByWindowStride(path_root, orientation, window_size, stride, emg1);
                groundTruthIndex1 = getMuscularActivityGroundTruth(emg1);


                features_name = "features_per_windowWin" + window_size + "Stride" + stride;

                sample1 = struct("emg", emg1, ...
                                    "features_name", features_name, ...
                                  features_name, features_per_window1, ...
                                  "groundTruthIndex", groundTruthIndex1, ...
                                  "gestureName", c);
                data1 = app.generateDataAugmentation(sample1);
                data = [data, data1];
            end
            
            
            known_dataset_training = [known_dataset_training, data];
            
            app.ButtonOnlineDataAugmentation.Visible = true;
            
        end

        % Value changed function: DropDownUserData
        function changeUserData(app, event)
            value = app.DropDownUserData.Value;
            app.setUserData(value);
        end

        % Button pushed function: SaveresultsButton
        function saveOnlineResults(app, event)
            global path_root
            global model_auto            
            
            model_dir = path_root + "App/matlab/data/saves/save_" + model_auto; % string(datetime());
            history_experiments = app.history_experiments; %#ok<ADPROPLC> 
            save(model_dir, "history_experiments");
            disp("saved");
            % uiwait(msgbox(sprintf("Saved"),'Success','modal'));
        end

        % Button pushed function: StartautoButton
        function startAuto(app, event)
            app.nextUser();
        end

        % Selection changed function: ClassmanagementButtonGroup
        function onClassManagementChange(app, event)
            % selectedButton = app.ClassmanagementButtonGroup.SelectedObject;
            global user_set
            if ~user_set
                app.setUserData(app.DropDownUserData.Value);
            end
            
            if app.ButtonWorkwithonlyknown.Value
                app.selectMode(0);
            elseif app.ButtonWorkwithnewclasses.Value 
                app.selectMode(1);
                
            end
        end

        % Cell selection callback: UITableOnlineExpansion
        function onTableNewClassSelection(app, event)
            global indices
            indices = event.Indices;
            
        end

        % Button pushed function: DeleteclassButton
        function deleteClassSelected(app, event)
            global indices model_incomplete_copy
            global probability_select base_classes stored_extra_classes
            if isempty(indices)
                return;
            end
            
            [m, n] = size(app.UITableOnlineExpansion.Data);
            
            
            app.UITableOnlineExpansion.Data(indices(1), :) = [];
            
            app.ButtonWorkwithnewclasses.Value = true;
            
            disp(indices);
            
            stored_extra_classes = [];
            
            if ~isempty(app.UITableOnlineExpansion.Data)
                data_table_raw = app.UITableOnlineExpansion.Data;
                
                data_table = {};
                j = 1;
                for i=1:length(data_table_raw)
                    if isempty(data_table_raw{i, 1})
                        continue;
                    end
                    data_table{j, 1} = char(data_table_raw{i});
                    j = j + 1;
                end
                
                                
                app.UITableOnlineExpansion.Data = data_table;
                
                
                table_len = length(data_table);
                
                for i=1:table_len
                    if isempty(data_table{i})
                        continue;
                    end
                    stored_extra_classes = [stored_extra_classes, string(data_table{i})];
                end
                
                total_classes = length(stored_extra_classes) + base_classes;
                probability_select = ones([1, total_classes])/total_classes;
                
            else
                
                app.UITableOnlineExpansion.ColumnFormat = {'char'};
                app.UITableOnlineExpansion.ColumnEditable = [true];
                app.UITableOnlineExpansion.Data = {};
                
                
                stored_extra_classes = [];
                
                probability_select = ones([1, base_classes])/base_classes;
            end


            stored_qnn.check = 0;
            app.transferNetwork(model_incomplete_copy, stored_extra_classes) %#ok<*NBRAK> 
            app.onlineQNNTest();


            % app.UITable.Data(end,:) = []; % Delete Row
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            app.main.Name = 'MATLAB App';
            app.Menu = uimenu(app.main);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.main);
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            app.UIAxesStaticSnapshot = uiaxes(app.StaticTab);
            title(app.UIAxesStaticSnapshot, 'EMG Snapshot')
            xlabel(app.UIAxesStaticSnapshot, 'Time [ms]')
            ylabel(app.UIAxesStaticSnapshot, {'A'; ''})
            zlabel(app.UIAxesStaticSnapshot, 'Z')
            app.PredictionsofmodelsPanel = uipanel(app.StaticTab);
            app.PredictionsofmodelsPanel.Title = 'Predictions of models';
            app.ImageStaticPredictionComplete = uiimage(app.PredictionsofmodelsPanel);
            app.ImageStaticPredictionComplete.ImageSource = 'unknown.png';
            app.ModelCompleteLabel = uilabel(app.PredictionsofmodelsPanel);
            app.ModelCompleteLabel.Text = {'Model'; 'Complete'};
            app.ImageStaticPredictionOnline = uiimage(app.PredictionsofmodelsPanel);
            app.ImageStaticPredictionOnline.ImageSource = 'unknown.png';
            app.ModelOnlineLabel = uilabel(app.PredictionsofmodelsPanel);
            app.ModelOnlineLabel.Text = {'Model'; 'Online'};
            app.ImageStaticPredictionIncomplete = uiimage(app.PredictionsofmodelsPanel);
            app.ImageStaticPredictionIncomplete.ImageSource = 'unknown.png';
            app.ModelIncompleteLabel = uilabel(app.PredictionsofmodelsPanel);
            app.ModelIncompleteLabel.Text = {'Model'; 'Incomplete'};
            app.LabelStaticModelsExecutionTime = uilabel(app.PredictionsofmodelsPanel);
            app.LabelStaticModelsExecutionTime.Text = '0 [ms]';
            app.ButtonStaticRewardPositive = uibutton(app.PredictionsofmodelsPanel, 'push');
            app.ButtonStaticRewardPositive.ButtonPushedFcn = createCallbackFcn(app, @feedbackPositive, true);
            app.ButtonStaticRewardPositive.Icon = 'reward_positive.png';
            app.ButtonStaticRewardPositive.Text = '';
            app.ButtonStaticRewardNegative = uibutton(app.PredictionsofmodelsPanel, 'push');
            app.ButtonStaticRewardNegative.ButtonPushedFcn = createCallbackFcn(app, @feedbackNegative, true);
            app.ButtonStaticRewardNegative.Icon = 'reward_negative.png';
            app.ButtonStaticRewardNegative.Text = '';
            app.ButtonStaticReadAndPredict = uibutton(app.StaticTab, 'push');
            app.ButtonStaticReadAndPredict.ButtonPushedFcn = createCallbackFcn(app, @staticReadAndPredict, true);
            app.ButtonStaticReadAndPredict.Text = 'Read Myo and predict';
            app.CheckBoxAutoSimulateStatic = uicheckbox(app.StaticTab);
            app.CheckBoxAutoSimulateStatic.Text = 'AutoSimulateStatic';
            app.StartautoButton = uibutton(app.StaticTab, 'push');
            app.StartautoButton.ButtonPushedFcn = createCallbackFcn(app, @startAuto, true);
            app.StartautoButton.Text = 'Start auto';
            app.CheckBoxIstraining = uicheckbox(app.StaticTab);
            app.CheckBoxIstraining.Text = 'Is training';
            app.LampReading = uilamp(app.StaticTab);
            app.LampReading.Enable = 'off';
            app.StreamingTab = uitab(app.TabGroup);
            app.StreamingTab.Title = 'Streaming';
            app.IsstreamingLampLabel = uilabel(app.StreamingTab);
            app.IsstreamingLampLabel.Text = 'Is streaming';
            app.IsstreamingLamp = uilamp(app.StreamingTab);
            app.IsstreamingLamp.Enable = 'off';
            app.button_play = uibutton(app.StreamingTab, 'push');
            app.button_play.ButtonPushedFcn = createCallbackFcn(app, @togglePlay, true);
            app.button_play.Icon = 'button_play.png';
            app.button_play.Text = '';
            app.DebugButton = uibutton(app.StreamingTab, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @debug, true);
            app.DebugButton.Text = 'Debug';
            app.SimulatorPanel = uipanel(app.main);
            app.SimulatorPanel.Title = 'Simulator';
            app.LampIsSimulatorOn = uilamp(app.SimulatorPanel);
            app.LampIsSimulatorOn.Enable = 'off';
            app.ImageMyoFake = uiimage(app.SimulatorPanel);
            app.ImageMyoFake.ImageSource = 'MyoFake.png';
            app.ButtonSimulatorFist = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorFist.ButtonPushedFcn = createCallbackFcn(app, @simulateFist, true);
            app.ButtonSimulatorFist.Icon = 'fist.png';
            app.ButtonSimulatorFist.Text = 'Fist';
            app.ButtonSimulatorOpen = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorOpen.ButtonPushedFcn = createCallbackFcn(app, @simulateOpen, true);
            app.ButtonSimulatorOpen.Icon = 'open.png';
            app.ButtonSimulatorOpen.Text = 'Open';
            app.ButtonSimulatorPinch = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorPinch.ButtonPushedFcn = createCallbackFcn(app, @simulatePinch, true);
            app.ButtonSimulatorPinch.Icon = 'pinch.png';
            app.ButtonSimulatorPinch.Text = 'Pinch';
            app.ButtonSimulatorWaveIn = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorWaveIn.ButtonPushedFcn = createCallbackFcn(app, @simulateWaveIn, true);
            app.ButtonSimulatorWaveIn.Icon = 'waveIn.png';
            app.ButtonSimulatorWaveIn.Text = 'WaveIn';
            app.ButtonSimulatorWaveOut = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorWaveOut.ButtonPushedFcn = createCallbackFcn(app, @simulateWaveOut, true);
            app.ButtonSimulatorWaveOut.Icon = 'waveOut.png';
            app.ButtonSimulatorWaveOut.Text = 'WaveOut';
            app.ButtonSimulatorRelax = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorRelax.ButtonPushedFcn = createCallbackFcn(app, @simulateRelax, true);
            app.ButtonSimulatorRelax.Icon = 'relax.png';
            app.ButtonSimulatorRelax.Text = 'Relax';
            app.OnLabel = uilabel(app.SimulatorPanel);
            app.OnLabel.Text = 'On';
            app.UserdataPanel = uipanel(app.main);
            app.UserdataPanel.Title = 'User data';
            app.UserDropDownLabel = uilabel(app.UserdataPanel);
            app.UserDropDownLabel.Text = 'User';
            app.DropDownUserData = uidropdown(app.UserdataPanel);
            app.DropDownUserData.Items = {};
            app.DropDownUserData.ValueChangedFcn = createCallbackFcn(app, @changeUserData, true);
            app.DropDownUserData.Value = {};
            app.CheckBoxAutoAddPinchOpen = uicheckbox(app.UserdataPanel);
            app.CheckBoxAutoAddPinchOpen.Text = 'Auto add pinch-open';
            app.CheckBoxAutoAddPinchOpen.Value = true;
            app.TabGroup2 = uitabgroup(app.main);
            app.EvaluationTab = uitab(app.TabGroup2);
            app.EvaluationTab.Title = 'Evaluation';
            app.UIAxesOnlineEvolutionClassification = uiaxes(app.EvaluationTab);
            title(app.UIAxesOnlineEvolutionClassification, 'Classification')
            xlabel(app.UIAxesOnlineEvolutionClassification, 'X')
            ylabel(app.UIAxesOnlineEvolutionClassification, 'Y')
            zlabel(app.UIAxesOnlineEvolutionClassification, 'Z')
            app.UIAxesOnlineEvolutionRecognition = uiaxes(app.EvaluationTab);
            title(app.UIAxesOnlineEvolutionRecognition, 'Recognition')
            xlabel(app.UIAxesOnlineEvolutionRecognition, 'X')
            ylabel(app.UIAxesOnlineEvolutionRecognition, 'Y')
            zlabel(app.UIAxesOnlineEvolutionRecognition, 'Z')
            app.ButtonTrainAndValidate = uibutton(app.EvaluationTab, 'push');
            app.ButtonTrainAndValidate.ButtonPushedFcn = createCallbackFcn(app, @onlineQNNTrain, true);
            app.ButtonTrainAndValidate.Text = 'Train';
            app.ButtonOnlineDataAugmentation = uibutton(app.EvaluationTab, 'push');
            app.ButtonOnlineDataAugmentation.ButtonPushedFcn = createCallbackFcn(app, @onlineAddRepresentative, true);
            app.ButtonOnlineDataAugmentation.Text = 'Add representative';
            app.ButtonTest = uibutton(app.EvaluationTab, 'push');
            app.ButtonTest.ButtonPushedFcn = createCallbackFcn(app, @onlineQNNTest, true);
            app.ButtonTest.Text = 'Test';
            app.SaveresultsButton = uibutton(app.EvaluationTab, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @saveOnlineResults, true);
            app.SaveresultsButton.Text = 'Save results';
            app.LabelOnlineResults = uilabel(app.EvaluationTab);
            app.LabelOnlineResults.Text = 'Results';
            app.LabelOnlineCountTest = uilabel(app.EvaluationTab);
            app.LabelOnlineCountTest.Text = 'Count: 0';
            app.ConfigTab = uitab(app.TabGroup2);
            app.ConfigTab.Title = 'Config';
            app.LabelConfigSummary = uilabel(app.ConfigTab);
            app.LabelConfigSummary.Text = 'Summary';
            app.TextAreaSummary = uitextarea(app.ConfigTab);
            app.ClassmanagementButtonGroup = uibuttongroup(app.ConfigTab);
            app.ClassmanagementButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @onClassManagementChange, true);
            app.ClassmanagementButtonGroup.Title = 'Class management';
            app.ButtonWorkwithonlyknown = uiradiobutton(app.ClassmanagementButtonGroup);
            app.ButtonWorkwithonlyknown.Text = 'Work with only known';
            app.ButtonWorkwithonlyknown.Value = true;
            app.ButtonWorkwithnewclasses = uiradiobutton(app.ClassmanagementButtonGroup);
            app.ButtonWorkwithnewclasses.Text = 'Work with new classes';
            app.UITableOnlineExpansion = uitable(app.ConfigTab);
            app.UITableOnlineExpansion.ColumnName = {'New class'};
            app.UITableOnlineExpansion.RowName = {};
            app.UITableOnlineExpansion.ColumnEditable = true;
            app.UITableOnlineExpansion.CellSelectionCallback = createCallbackFcn(app, @onTableNewClassSelection, true);
            app.ExpandclassButton = uibutton(app.ConfigTab, 'push');
            app.ExpandclassButton.ButtonPushedFcn = createCallbackFcn(app, @addNewClassToOnline, true);
            app.ExpandclassButton.Text = 'Expand class';
            app.DeleteclassButton = uibutton(app.ConfigTab, 'push');
            app.DeleteclassButton.ButtonPushedFcn = createCallbackFcn(app, @deleteClassSelected, true);
            app.DeleteclassButton.Text = 'Delete class';
            app.EditFieldNewClass = uieditfield(app.ConfigTab, 'text');
            app.EditFieldNewClass.Placeholder = 'new class here';
            app.ONLINELEARNINGLabel = uilabel(app.main);
            app.ONLINELEARNINGLabel.Text = 'ONLINE LEARNING';
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appUIMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end