classdef appUIMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                  matlab.ui.Figure
        Menu                  matlab.ui.container.Menu
        Menu2                 matlab.ui.container.Menu
        TabGroup              matlab.ui.container.TabGroup
        StreamingTab          matlab.ui.container.Tab
        button_play           matlab.ui.control.Button
        IsstreamingLamp       matlab.ui.control.Lamp
        IsstreamingLampLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        StaticTab             matlab.ui.container.Tab
    end

    
    properties (Access = private)
        is_playing = false; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: button_play
        function togglePlay(app, event)
            if app.is_playing
                app.is_playing = false;
                app.button_play.Icon = 'button_play.png';
            else
                app.is_playing = true;
                app.button_play.Icon = 'button_pause.png';
            end
            
            app.IsstreamingLamp.Enable = app.is_playing;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            app.main.Name = 'MATLAB App';
            app.Menu = uimenu(app.main);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.main);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.main);
            app.StreamingTab = uitab(app.TabGroup);
            app.StreamingTab.Title = 'Streaming';
            app.UIAxes = uiaxes(app.StreamingTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IsstreamingLampLabel = uilabel(app.StreamingTab);
            app.IsstreamingLampLabel.Text = 'Is streaming';
            app.IsstreamingLamp = uilamp(app.StreamingTab);
            app.IsstreamingLamp.Enable = 'off';
            app.button_play = uibutton(app.StreamingTab, 'push');
            app.button_play.ButtonPushedFcn = createCallbackFcn(app, @togglePlay, true);
            app.button_play.Icon = 'button_play.png';
            app.button_play.Text = '';
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appUIMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end